classdef ClassicGeneticAlgorithmForPID < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ClassicGeneticAlgorithmforPIDTunningUIFigure  matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        SimulationResultsPanel        matlab.ui.container.Panel
        SimulationStepsTextSR         matlab.ui.control.Label
        BestGuessTextSR               matlab.ui.control.Label
        kd_output                     matlab.ui.control.NumericEditField
        KdTextSR                      matlab.ui.control.Label
        ki_output                     matlab.ui.control.NumericEditField
        KiTextSR                      matlab.ui.control.Label
        kp_output                     matlab.ui.control.NumericEditField
        KpTextSR                      matlab.ui.control.Label
        fitnessvalue_output           matlab.ui.control.NumericEditField
        FitnessValueTextSR            matlab.ui.control.Label
        generation_output             matlab.ui.control.NumericEditField
        GenerationTextSR              matlab.ui.control.Label
        OutputGraph                   matlab.ui.control.UIAxes
        StarttheSimulationButton      matlab.ui.control.Button
        TabGroup                      matlab.ui.container.TabGroup
        PlantModelSettingsTab         matlab.ui.container.Tab
        SavedStatusOutput             matlab.ui.control.Label
        DescriptionTextPlantModel_2   matlab.ui.control.Label
        DescriptionTextPlantModel     matlab.ui.control.Label
        SaveButton                    matlab.ui.control.Button
        UnderlineTextPlantModel       matlab.ui.control.Label
        sTextPlantModel               matlab.ui.control.Label
        delay_input                   matlab.ui.control.NumericEditField
        NegativeTextPlantModel        matlab.ui.control.Label
        EulerTextPlantModel           matlab.ui.control.Label
        DotTextPlantModel             matlab.ui.control.Label
        SPlusOneTextPlantModel        matlab.ui.control.Label
        firstorder_input              matlab.ui.control.NumericEditField
        gain_input                    matlab.ui.control.NumericEditField
        UnderlineFractionTextPlantModel  matlab.ui.control.Label
        PlantModelText                matlab.ui.control.Label
        AlgorithmSettingsTab          matlab.ui.container.Tab
        RequiredChancesText           matlab.ui.control.Label
        EndingConditionText           matlab.ui.control.Label
        EnvironmentSettingsText       matlab.ui.control.Label
        inversionchance_input         matlab.ui.control.NumericEditField
        InversionTextCGAS             matlab.ui.control.Label
        mutationchance_input          matlab.ui.control.NumericEditField
        MutationTextCGAS              matlab.ui.control.Label
        crossoverchance_input         matlab.ui.control.NumericEditField
        CrossoverTextCGAS             matlab.ui.control.Label
        maxgeneration_input           matlab.ui.control.NumericEditField
        MaxGenerationTextCGAS         matlab.ui.control.Label
        fitnessbigger_input           matlab.ui.control.NumericEditField
        FitnessFunctionTextCGAS       matlab.ui.control.Label
        referencevalue_input          matlab.ui.control.NumericEditField
        ReferenceValueEditFieldLabel  matlab.ui.control.Label
        populationsize_input          matlab.ui.control.NumericEditField
        PopulationSizeEditFieldLabel  matlab.ui.control.Label
        UnderlineTextCGAS             matlab.ui.control.Label
        ClassicGeneticAlgorithmSettingsLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        LIMIT_NUMBER = 128;
        PlantModel          % Which stores the TF object of the Plant Model.
        Chances = [0.5, 0.5, 0.5]; % Stores the chances for Mut, CO and Inv.
        MaxGeneration = 20; % Stores the maximum generation of the same best.
        ReferenceValue = 1; % Stores the reference value for system.
        FitnessValueToStop = -0.4; % Stores the ending condition of FF.
        PopulationSize = 20; % Stores the population size genetered.
        
        % Results
        BestPIDResult;
        BestFitnessValue;
        GenerationCreated;
    end
    
    methods (Access = private)
        
        function [best_result, fitness_of_best] = ...
        ClassicGeneticAlgorithm(app, PlantModel, InitialPopulationCount, ReferenceValue, ...
                                EndingCondFFValue, EndingCondGenCount, ...
                                ChanceCrossover, ChanceMutation, ChanceInversion)
            % Create the initial population, and start the algorithm.
            population = app.CreateInitialPopulation(InitialPopulationCount);
            sorted_population = app.ProcessAndSortFitness(population, ...
                                                      PlantModel, ...
                                                      ReferenceValue);
            % If the generation will be same for EndingCondGenCount generation,
            % exit the program.
            being_same_generation = 0;
            previous_best = sorted_population(:, 1);
            % Count the generation for investigation purposes.
            app.GenerationCreated = 1;
        
            % If the ending condition is not met, continue the process.
            while sorted_population(4, 1) <= EndingCondFFValue && ...
                    being_same_generation <= EndingCondGenCount
                
                app.generation_output.Value = app.GenerationCreated;
        
                % Select best two individuals, put them into the population.
                best_two = [sorted_population(:, 1), sorted_population(:, 2)];
                population(:, [1, 2]) = best_two(1:3, :);
        
                % Select individuals for crossover, mutation and inversion.
                [group_mas, group_pas] = app.SelectionMethod(sorted_population, ...
                                                        InitialPopulationCount-2);
        
                % Travel throught to apply mutation, inversion, and crossover.
                for index = 1:size(group_pas, 2)
                    % Convert PID values into gens.
                    MA_PID_chromosomes = app.PIDtoBinaryGens(group_mas(1:3, index));
                    PA_PID_chromosomes = app.PIDtoBinaryGens(group_pas(1:3, index));
        
                    % Apply Crossover
                    crossovered_chromosome(1, :) = app.CGA_Crossover( ...
                                                   MA_PID_chromosomes(1, :),...
                                                   PA_PID_chromosomes(1, :),...
                                                   ChanceCrossover ...
                                                   );
                    crossovered_chromosome(2, :) = app.CGA_Crossover( ...
                                                   MA_PID_chromosomes(2, :),...
                                                   PA_PID_chromosomes(2, :),...
                                                   ChanceCrossover ...
                                                   );
                    crossovered_chromosome(3, :) = app.CGA_Crossover( ...
                                                   MA_PID_chromosomes(3, :),...
                                                   PA_PID_chromosomes(3, :),...
                                                   ChanceCrossover ...
                                                   );
                    
                    % Apply Mutation
                    mutated_chromosome(1, :) = app.CGA_Mutation(...
                                               crossovered_chromosome(1, :),...
                                               ChanceMutation ...
                                               );
                    mutated_chromosome(2, :) = app.CGA_Mutation(...
                                               crossovered_chromosome(2, :),...
                                               ChanceMutation ...
                                               );
                    mutated_chromosome(3, :) = app.CGA_Mutation(...
                                               crossovered_chromosome(3, :),...
                                               ChanceMutation ...
                                               );
        
                    % Apply Inversion
                    inverted_chromosome(1, :) = app.CGA_Inversion( ...
                                                mutated_chromosome(1, :),...
                                                ChanceInversion ...
                                                );
                    inverted_chromosome(2, :) = app.CGA_Inversion( ...
                                                mutated_chromosome(2, :),...
                                                ChanceInversion ...
                                                );
                    inverted_chromosome(3, :) = app.CGA_Inversion( ...
                                                mutated_chromosome(3, :),...
                                                ChanceInversion ...
                                                );
        
                    % Convert binary gens into decimal PID format.
                    PID_format = app.BinaryGenstoPID(inverted_chromosome);
                    
                    % Check for PID if 0 or LIMIT_NUMBER.
                    if PID_format(1) == 0, PID_format(1) = 1; end
                    if PID_format(1) == app.LIMIT_NUMBER, PID_format(1) = app.LIMIT_NUMBER - 1; end
                    if PID_format(2) == 0, PID_format(2) = 1; end
                    if PID_format(2) == app.LIMIT_NUMBER, PID_format(2) = app.LIMIT_NUMBER - 1; end
                    if PID_format(3) == 0, PID_format(3) = 1; end
                    if PID_format(3) == app.LIMIT_NUMBER, PID_format(3) = app.LIMIT_NUMBER - 1; end
        
                    % Assert into the population.
                    population(1:3, index + 2) = PID_format;
                end
        
                % New generation created after previous for loop.
                app.GenerationCreated = app.GenerationCreated + 1;
        
                % Put them into fitness function, and sort the new generation.
                sorted_population = app.ProcessAndSortFitness(population, ...
                                                          PlantModel, 5);
                
                % If the previous_best is the same as this best, increase
                % the number of being_same_generation.
                if (previous_best(:) == sorted_population(:, 1))
                    being_same_generation = being_same_generation + 1;
                else
                    % Update the previous best.
                    previous_best(:) = sorted_population(:, 1);
                end
            end
        
            % If the ending condition met, stop the process, and return.
            best_result = sorted_population([1, 2, 3], 1);
            fitness_of_best = sorted_population(4, 1);
        
        end
        
        function [overshoot_error, stability_time, error_given_time] ...
         = AnalyseSystemResult(~, res_t, res_y, ref_val, error_at_time)

            % Get the size of timing.
            [size_of_time, ~] = size(res_t);
        
            % Check for the parameters if they are given.
            if ~exist("error_at_time", "var")
                error_at_time = res_t(size_of_time - 5);
            end
            
            % Find the overshoot:
            %   Overshoot is the maximum distance that our function
            %   rises comparing to our reference.
            overshoot = max(res_y);
            if (overshoot < ref_val)
                overshoot_error = 0;
            else
                overshoot_error = (overshoot - ref_val) / ref_val;
            end
        
            % Find the necessary time to achieve stability,
            % and if it does not until the max time, find
            % the error rate at the ending time.
            % According to needs, change the stability criteria.
            stability_criteria = 0.05;
            stability_time = 0;
        
            for index = 1 : (size_of_time - 5)
                checking_values = res_y(index : (index + 5));
                average_values = mean(checking_values);
        
                % Check if the average inside the stability criteria.
                current_error = abs(average_values - ref_val) / ref_val;
                if current_error <= stability_criteria
                    stability_time = res_t(index);
                end
        
                % Check if it is in error_at_time state.
                if res_t(index) == error_at_time
                    error_given_time = current_error;
                end
        
                % If loop reaches the end of the time, stability
                % doesn't exist, and return -1.
                if index == (size_of_time - 5) && stability_time == 0
                    stability_time = -1;
                end
            end
        end    
        
        function PID_Coefficents = BinaryGenstoPID(~, CombinedGen)
            [~, NucleoditSize] = size(CombinedGen);
        
           % Check if nucleodit size is even number.
            if (mod(NucleoditSize, 2) ~= 0)
                error("Nucleodit size has to be even number.")
            end
        
            PID_Coefficents = [
                bin2dec(CombinedGen(1, 1:(NucleoditSize/2))) + 0.01 * bin2dec(CombinedGen(1, (NucleoditSize/2 + 1):end));
                bin2dec(CombinedGen(2, 1:(NucleoditSize/2))) + 0.01 * bin2dec(CombinedGen(2, (NucleoditSize/2 + 1):end));
                bin2dec(CombinedGen(3, 1:(NucleoditSize/2))) + 0.01 * bin2dec(CombinedGen(3, (NucleoditSize/2 + 1):end))
           ];
        end
        
        function new_chromosome = CGA_Crossover(~, choromosome_ma, choromosome_pa, ...
                                        crossover_chance)
    
            % Check if the chromosomes have same gen amount.
            if (size(choromosome_ma, 2) ~= size(choromosome_pa, 2))
                error("Chromosomes must be in same length.");
            end
            
            % If crossover chance reached, do the process.
            active_chance = randi(100) / 100;
            if active_chance >= crossover_chance
                % Pre-allocate the array.
                new_chromosome = repmat('0', 1, size(choromosome_pa, 2));
            
                % Decide the slice edge for chromosome_ma.
                edge_percentage = randi(100) / 100;
                selection_edge = ceil(size(choromosome_ma, 2) * edge_percentage);
            
                % Assign the values.
                new_chromosome(1, 1:selection_edge) = choromosome_ma(1, 1:selection_edge);
                new_chromosome(1, (selection_edge+1):end) = ...
                          choromosome_pa(1, (selection_edge+1):size(choromosome_pa, 2));
            
                % If crossover chance couldn't achieved, assign the mother chromosome
            % into the child.
            else
                new_chromosome = choromosome_ma;
            end
        end
        
        function chromosome = CGA_Inversion(~, chromosome, inversion_chance)
            % If the chance achieved, then do the inversion.
            active_chance = randi(100) / 100;
            if active_chance >= inversion_chance
        
                % Init two random value, and assign them into start and end indices.
                rand_1 = randi(size(chromosome, 2));
                rand_2 = randi(size(chromosome, 2));
                
                if (rand_1 < rand_2)
                    starting_index = rand_1;
                    ending_index = rand_2;
                else
                    starting_index = rand_2;
                    ending_index = rand_1;
                end
            
                % Flip the bits.
                for gen_index = starting_index:ending_index
                    if (chromosome(1, gen_index) == '0')
                        chromosome(1, gen_index) = '1';
                    else
                        chromosome(1, gen_index) = '0';
                    end
                end
        
        
            end
        end
        
        function mutated_chromosome = CGA_Mutation(~, original_chromosome, change_chance)
            mutated_chromosome = original_chromosome;
        
            for index = 1:size(original_chromosome, 2)
                random_chance = randi(100) / 100;
        
                % If the randomized chance bigger then chance of change, flip it.
                if random_chance >= change_chance
                    char_chance = randi(100);
                    
                    % Change char_chance into the real gen value.
                    if char_chance >= 50
                        char_chance = '1';
                    else
                        char_chance = '0';
                    end
        
                    mutated_chromosome(1, index) = char_chance;
                end
        
            end
        end
        
        function initial_population = CreateInitialPopulation(~, PopSize)
            initial_population = 100 * rand([3 PopSize]);
        end

        function plant = CreatePlantObject(~, k, T, tau)
            plant = tf(k, [T 1], 'InputDelay', tau);
        end
        
        function fitness_value = FitnessFunction(app, PlantObject, RefValue, PID_coeff)
            [amps, timescale] = app.SimulatePIDSystem(RefValue, PID_coeff, PlantObject);
            [overshoot, stability_time, err_given_t] = app.AnalyseSystemResult(timescale, amps, RefValue);
           
            % Definitations for Fitness Function
            weight_t = 0.5;
            weight_o = 0.4;
            weight_e = 0.1;
        
            % Get the last time of time scale.
            stability_time_ratio = stability_time / timescale(end);
            
            % Create the fitness value. Bigger value means the most fittest.
            fitness_value = -(weight_t * stability_time_ratio + ...
                              weight_o * overshoot            + ...
                              weight_e * err_given_t            ...
                              );
                        
        end
        
        function [EachCombined, BinaryGens] = PIDtoBinaryGens(app, PIDCoefficents)        
            % Check for argument's size.
            if size(PIDCoefficents) ~= [3, 1]
                error("The argument provided is incorrect size.");
            end
        
            Kp = PIDCoefficents(1);
            Ki = PIDCoefficents(2);
            Kd = PIDCoefficents(3);
        
            % Save 13.1257 as [13, 12], or 53.987 as [53, 98]
            Kp_arr = [floor(Kp), floor((Kp - floor(Kp))*100)];
            Ki_arr = [floor(Ki), floor((Ki - floor(Ki))*100)];
            Kd_arr = [floor(Kd), floor((Kd - floor(Kd))*100)];
        
            % Check the numbers if they are bigger than limit number.
            if Kp_arr(1) >= app.LIMIT_NUMBER || ...
               Kp_arr(2) >= app.LIMIT_NUMBER || ...
               Ki_arr(1) >= app.LIMIT_NUMBER || ...
               Ki_arr(2) >= app.LIMIT_NUMBER || ...
               Kd_arr(1) >= app.LIMIT_NUMBER || ...
               Kd_arr(2) >= app.LIMIT_NUMBER
                warning("You shouldn't put a number which has more than 7 digits" + ...
                      " in binary format, or put a number that is bigger than 127.")
        
            end
        
            % Correct each setting if they are 0 or 128.
            Kp_arr(Kp_arr == 0) = Kp_arr(Kp_arr == 0) + 1;
            Kp_arr(Kp_arr == app.LIMIT_NUMBER) = Kp_arr(Kp_arr == app.LIMIT_NUMBER) - 1;
            Ki_arr(Ki_arr == 0) = Ki_arr(Ki_arr == 0) + 1;
            Ki_arr(Ki_arr == app.LIMIT_NUMBER) = Ki_arr(Ki_arr == app.LIMIT_NUMBER) - 1;
            Kd_arr(Kd_arr == 0) = Kd_arr(Kd_arr == 0) + 1;
            Kd_arr(Kd_arr == app.LIMIT_NUMBER) = Kd_arr(Kd_arr == app.LIMIT_NUMBER) - 1;
        
            % Convert each decimal into binary.
            Kp_bin = dec2bin(Kp_arr, floor(log2(app.LIMIT_NUMBER)));
            Ki_bin = dec2bin(Ki_arr, floor(log2(app.LIMIT_NUMBER)));
            Kd_bin = dec2bin(Kd_arr, floor(log2(app.LIMIT_NUMBER)));
        
            % Return BinaryGens.
            % Format: [Kp_int, Kp_float; Ki_int, Ki_float, Kd_int, Kd_float];
            BinaryGens = [Kp_bin; Ki_bin; Kd_bin];
            
            % Return EachCombined
            Kp_combined(1:7) = Kp_bin(1, :);
            Kp_combined(8:14) = Kp_bin(2, :);
            Ki_combined(1:7) = Ki_bin(1, :);
            Ki_combined(8:14) = Ki_bin(2, :);
            Kd_combined(1:7) = Kd_bin(1, :);
            Kd_combined(8:14) = Kd_bin(2, :);
            EachCombined = [Kp_combined; Ki_combined; Kd_combined];
        end
        
        function SortedFitness = ProcessAndSortFitness(app, PID_Population, PlantObject, RefValue)            
            [~, population_size] = size(PID_Population);
            SortedFitness = zeros([4, population_size]);
            
            % Assign fourth row as Fitness Values.
            for index = 1:1:population_size
                FitnesValue = app.FitnessFunction(PlantObject, RefValue, PID_Population(:, index));
                SortedFitness(:, index) = [PID_Population(:, index); FitnesValue];
            end
        
            % Sort the PID Gains according to their Fitness Values.
            SortedFitness = sortrows(SortedFitness', 4, "descend")';
            
            % Display in the GUI
            app.kp_output.Value = SortedFitness(1, 1);
            app.ki_output.Value = SortedFitness(2, 1);
            app.kd_output.Value = SortedFitness(3, 1); 
            app.fitnessvalue_output.Value = SortedFitness(4, 1);

        end
        
        function [group_1, group_2] = SelectionMethod(~, Individiuals, PairCount)            
            % Get the sizes of individuals.
            IndividiualsSize = size(Individiuals, 2);
            
            % Select the groups which will be fathers and mothers.
            group_1 = [Individiuals(:, randi(IndividiualsSize, ...
                                          [1, PairCount]))];
            group_2 = [Individiuals(:, randi(IndividiualsSize, ...
                                          [1, PairCount]))];
        
            % Shuffle the groups.
            group_1 = group_1(:, randi(size(group_1, 2), [1, PairCount]));
            group_2 = group_2(:, randi(size(group_2, 2), [1, PairCount]));
        end
        
        function [y_data, x_data] = SimulatePIDSystem(~, ref_val, PID_gains, plant_model)
            % Create the PID controller with given gains.
            pid_controller = pid(PID_gains(1), 1/PID_gains(2), 1/PID_gains(3));
            % Create a system to simulate.
            feedback_system = feedback(pid_controller * plant_model, 1);
            % Simulate the system with given reference step function.
            step_options = stepDataOptions('StepAmplitude', ref_val);
            [y_data, x_data] = step(feedback_system, step_options);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            app.PlantModel = app.CreatePlantObject(app.gain_input.Value,...
                                                   app.firstorder_input.Value,...
                                                   app.delay_input.Value);
            app.SavedStatusOutput.Text = "Saved";
        end

        % Button pushed function: StarttheSimulationButton
        function StarttheSimulationButtonPushed(app, event)
            % Get the data from textboxes
            app.ReferenceValue = app.referencevalue_input.Value;
            app.PopulationSize = app.populationsize_input.Value;
            app.MaxGeneration = app.maxgeneration_input.Value;
            app.FitnessValueToStop = app.fitnessbigger_input.Value;
            app.Chances(1) = app.crossoverchance_input.Value / 100;
            app.Chances(2) = app.mutationchance_input.Value / 100;
            app.Chances(3) = app.inversionchance_input.Value / 100;

            % Start the Algorithm
            [app.BestPIDResult, app.BestFitnessValue]...
                = app.ClassicGeneticAlgorithm(app.PlantModel, ...
                                              app.PopulationSize,...
                                              app.ReferenceValue,...
                                              app.FitnessValueToStop,...
                                              app.MaxGeneration,...
                                              app.Chances(1),...
                                              app.Chances(2),...
                                              app.Chances(3)...
                                              );

            % Show the Results in the GUI
            app.kp_output.Value = app.BestPIDResult(1);
            app.ki_output.Value = app.BestPIDResult(2);
            app.kd_output.Value = app.BestPIDResult(3);
            app.fitnessvalue_output.Value = app.BestFitnessValue;

            % Print to figure.
            app.OutputGraph.Visible = "on";
            [y_data, x_data] = app.SimulatePIDSystem(app.ReferenceValue,...
                                                     app.BestPIDResult,...
                                                     app.PlantModel);
            plot(app.OutputGraph, y_data, x_data);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ClassicGeneticAlgorithmforPIDTunningUIFigure = uifigure('Visible', 'off');
            app.ClassicGeneticAlgorithmforPIDTunningUIFigure.Name = 'Classic Genetic Algorithm for PID Tunning';
            app.GridLayout = uigridlayout(app.ClassicGeneticAlgorithmforPIDTunningUIFigure);
            app.TabGroup = uitabgroup(app.GridLayout);
            app.PlantModelSettingsTab = uitab(app.TabGroup);
            app.PlantModelSettingsTab.Title = 'Plant Model Settings';
            app.PlantModelText = uilabel(app.PlantModelSettingsTab);
            app.PlantModelText.Text = 'Plant Model';
            app.UnderlineFractionTextPlantModel = uilabel(app.PlantModelSettingsTab);
            app.UnderlineFractionTextPlantModel.Text = '__________';
            app.gain_input = uieditfield(app.PlantModelSettingsTab, 'numeric');
            app.firstorder_input = uieditfield(app.PlantModelSettingsTab, 'numeric');
            app.SPlusOneTextPlantModel = uilabel(app.PlantModelSettingsTab);
            app.SPlusOneTextPlantModel.Text = 's + 1';
            app.DotTextPlantModel = uilabel(app.PlantModelSettingsTab);
            app.DotTextPlantModel.Text = '.';
            app.EulerTextPlantModel = uilabel(app.PlantModelSettingsTab);
            app.EulerTextPlantModel.Text = 'e';
            app.NegativeTextPlantModel = uilabel(app.PlantModelSettingsTab);
            app.NegativeTextPlantModel.Text = '-';
            app.delay_input = uieditfield(app.PlantModelSettingsTab, 'numeric');
            app.sTextPlantModel = uilabel(app.PlantModelSettingsTab);
            app.sTextPlantModel.Text = 's';
            app.UnderlineTextPlantModel = uilabel(app.PlantModelSettingsTab);
            app.UnderlineTextPlantModel.Text = '__________________';
            app.SaveButton = uibutton(app.PlantModelSettingsTab, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.DescriptionTextPlantModel = uilabel(app.PlantModelSettingsTab);
            app.DescriptionTextPlantModel.Text = {'You have to set the necesarry coefficients for your'; 'plant. This plant has to be a first-order with inertia'; 'model.'};
            app.DescriptionTextPlantModel_2 = uilabel(app.PlantModelSettingsTab);
            app.DescriptionTextPlantModel_2.Text = 'Status:';
            app.SavedStatusOutput = uilabel(app.PlantModelSettingsTab);
            app.SavedStatusOutput.Text = 'Not Saved';
            app.AlgorithmSettingsTab = uitab(app.TabGroup);
            app.AlgorithmSettingsTab.Title = 'Algorithm Settings';
            app.ClassicGeneticAlgorithmSettingsLabel = uilabel(app.AlgorithmSettingsTab);
            app.ClassicGeneticAlgorithmSettingsLabel.Text = {'Classic Genetic'; 'Algorithm Settings'};
            app.UnderlineTextCGAS = uilabel(app.AlgorithmSettingsTab);
            app.UnderlineTextCGAS.Text = '__________________';
            app.PopulationSizeEditFieldLabel = uilabel(app.AlgorithmSettingsTab);
            app.PopulationSizeEditFieldLabel.Text = 'Population Size';
            app.populationsize_input = uieditfield(app.AlgorithmSettingsTab, 'numeric');
            app.ReferenceValueEditFieldLabel = uilabel(app.AlgorithmSettingsTab);
            app.ReferenceValueEditFieldLabel.Text = 'Reference Value';
            app.referencevalue_input = uieditfield(app.AlgorithmSettingsTab, 'numeric');
            app.FitnessFunctionTextCGAS = uilabel(app.AlgorithmSettingsTab);
            app.FitnessFunctionTextCGAS.Text = 'Fitness Function >=';
            app.fitnessbigger_input = uieditfield(app.AlgorithmSettingsTab, 'numeric');
            app.MaxGenerationTextCGAS = uilabel(app.AlgorithmSettingsTab);
            app.MaxGenerationTextCGAS.Text = 'Max Generation:';
            app.maxgeneration_input = uieditfield(app.AlgorithmSettingsTab, 'numeric');
            app.CrossoverTextCGAS = uilabel(app.AlgorithmSettingsTab);
            app.CrossoverTextCGAS.Text = 'Crossover %';
            app.crossoverchance_input = uieditfield(app.AlgorithmSettingsTab, 'numeric');
            app.MutationTextCGAS = uilabel(app.AlgorithmSettingsTab);
            app.MutationTextCGAS.Text = 'Mutation %';
            app.mutationchance_input = uieditfield(app.AlgorithmSettingsTab, 'numeric');
            app.InversionTextCGAS = uilabel(app.AlgorithmSettingsTab);
            app.InversionTextCGAS.Text = 'Inversion %';
            app.inversionchance_input = uieditfield(app.AlgorithmSettingsTab, 'numeric');
            app.EnvironmentSettingsText = uilabel(app.AlgorithmSettingsTab);
            app.EnvironmentSettingsText.Text = 'Environment Settings';
            app.EndingConditionText = uilabel(app.AlgorithmSettingsTab);
            app.EndingConditionText.Text = 'Ending Condition';
            app.RequiredChancesText = uilabel(app.AlgorithmSettingsTab);
            app.RequiredChancesText.Text = 'Required Chances';
            app.StarttheSimulationButton = uibutton(app.GridLayout, 'push');
            app.StarttheSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @StarttheSimulationButtonPushed, true);
            app.StarttheSimulationButton.Text = 'Start the Simulation';
            app.SimulationResultsPanel = uipanel(app.GridLayout);
            app.SimulationResultsPanel.Title = 'Simulation Results';
            app.OutputGraph = uiaxes(app.SimulationResultsPanel);
            title(app.OutputGraph, 'Output')
            app.OutputGraph.Visible = 'off';
            app.GenerationTextSR = uilabel(app.SimulationResultsPanel);
            app.GenerationTextSR.Text = 'Generation:';
            app.generation_output = uieditfield(app.SimulationResultsPanel, 'numeric');
            app.generation_output.Editable = 'off';
            app.FitnessValueTextSR = uilabel(app.SimulationResultsPanel);
            app.FitnessValueTextSR.Text = 'Fitness Value:';
            app.fitnessvalue_output = uieditfield(app.SimulationResultsPanel, 'numeric');
            app.fitnessvalue_output.Editable = 'off';
            app.KpTextSR = uilabel(app.SimulationResultsPanel);
            app.KpTextSR.Text = 'Kp:';
            app.kp_output = uieditfield(app.SimulationResultsPanel, 'numeric');
            app.kp_output.Editable = 'off';
            app.KiTextSR = uilabel(app.SimulationResultsPanel);
            app.KiTextSR.Text = 'Ki:';
            app.ki_output = uieditfield(app.SimulationResultsPanel, 'numeric');
            app.ki_output.Editable = 'off';
            app.KdTextSR = uilabel(app.SimulationResultsPanel);
            app.KdTextSR.Text = 'Kd:';
            app.kd_output = uieditfield(app.SimulationResultsPanel, 'numeric');
            app.kd_output.Editable = 'off';
            app.BestGuessTextSR = uilabel(app.SimulationResultsPanel);
            app.BestGuessTextSR.Text = 'Best Guess';
            app.SimulationStepsTextSR = uilabel(app.SimulationResultsPanel);
            app.SimulationStepsTextSR.Text = 'Simulation Steps';
            % Show the figure after all components are created
            app.ClassicGeneticAlgorithmforPIDTunningUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ClassicGeneticAlgorithmForPID

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.ClassicGeneticAlgorithmforPIDTunningUIFigure)
            else

                % Focus the running singleton app
                figure(runningApp.ClassicGeneticAlgorithmforPIDTunningUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ClassicGeneticAlgorithmforPIDTunningUIFigure)
        end
    end
end